classdef JRRGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button              matlab.ui.control.Button
        JugarButton         matlab.ui.control.Button
        JRRGameLabel        matlab.ui.control.Label
        NivelDropDownLabel  matlab.ui.control.Label
        NivelDropDown       matlab.ui.control.DropDown
        CrearNivelButton    matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: JugarButton
        function Jugar(app, event)
           dibujarNivel(str2double(app.NivelDropDown.Value));
        end

        % Button pushed function: CrearNivelButton
        function CrearNivelButtonPushed(app, event)
            sup =['*tif;*TIF;*.tiff;*.TIFF;*.jpg;*.jpeg;*.JPG;*.JPEG;', ...
       '*.tif;*.TIF;*.tiff;*.TIFF;*.gif;*.GIF;*.bmp;*.BMP;*.png;*PNG'];  
           ftype =  {sup,  'Supported: jpg, tif, bmp, gif, png ...'; ...     
           '*.jpg;*.jpeg;*.JPG;*.JPEG',  'JPEG'; ...
           '*.tif;*.TIF;*.tiff;*.TIFF',  'TIF'; ... 
           '*.gif;*.GIF;',               'GIF'; ...
           '*.bmp;*.BMP;',               'BMP'; ...
           '*.png;*.PNG;',               'PNG'; ...
           '*.*',                        'All Files (*.*)'};
           file = uigetfile(ftype);
           img = imread(file);
           matrixMap = reconoce(img);
           dibujarMapa(matrixMap);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'Portada.png';
            app.Button.Text = '';
            app.JugarButton = uibutton(app.UIFigure, 'push');
            app.JugarButton.ButtonPushedFcn = createCallbackFcn(app, @Jugar, true);
            app.JugarButton.Text = 'Jugar';
            app.JRRGameLabel = uilabel(app.UIFigure);
            app.JRRGameLabel.Text = 'JRR Game';
            app.NivelDropDownLabel = uilabel(app.UIFigure);
            app.NivelDropDownLabel.Text = 'Nivel';
            app.NivelDropDown = uidropdown(app.UIFigure);
            app.NivelDropDown.Items = {'1', '2', '3', '4', '5'};
            app.NivelDropDown.Value = '1';
            app.CrearNivelButton = uibutton(app.UIFigure, 'push');
            app.CrearNivelButton.ButtonPushedFcn = createCallbackFcn(app, @CrearNivelButtonPushed, true);
            app.CrearNivelButton.Text = 'Crear Nivel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = JRRGame

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end